classdef showFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PointTransformButton  matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        TextAreaLabel         matlab.ui.control.Label
        GetDataButton         matlab.ui.control.Button
        Label2                matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Button                matlab.ui.control.Button
    end

    
    properties (Access = public)
        fig1,fig2
        pnts1_matrix, pnts2_matrix
        count;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            pc1 = pcread("RealsenseB2-cleaned.ply");
            pc2 = pcread("RealsenseA2.ply");
            app.pnts1_matrix = zeros(3,5);
            app.pnts2_matrix = zeros(3,5);
            [app.fig1,app.fig2]=showFigs(pc1,pc2);
            app.count=0;
            %[pnts1_matrix, pnts2_matrix] = getPointPair(fig1, fig2);
            %app.Label.Text=pnts1_matrix;
            %[pnts1_matrix, pnts2_matrix] = performFigPntsDuo(pc1, pc2);
            %createfigure(pc1.Location(:,1),pc1.Location(:,2),pc1.Location(:,3),pc1.Count,pc1.Color);
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            app.count=app.count+1;
            [pnt1, pnt2] = getSinglePointPair(app.fig1, app.fig2);
            app.pnts1_matrix(:,app.count)=pnt1;
            app.pnts2_matrix(:,app.count)=pnt2;
            pnt1_message= "Point1 selection: " + mat2str(pnt1);
            pnt2_message= "Point2 selection: " + mat2str(pnt2);
            %storedval=app.TextArea.Value;
            %mystring=sprintf("Point selection " + string(app.count)+ "\n"+"Points1: \n" + mat2str(app.pnts1_matrix) + "\n"+"Points2: \n" +mat2str(app.pnts2_matrix) +"\n");
            mystring=sprintf("Point selection " + string(app.count)+ "\n"+ pnt1_message + "\n"+ pnt2_message+"\n");
            app.TextArea.Value=[app.TextArea.Value;mystring];

           % app.TextArea.Value = sprintf("Point selection " + string(app.count)+ newline);
            %message=compose(my_message);
%             if strcmp(app.TextArea.Value,'')
%                   message_disp = message;
%               else
%                   %message_disp = [app.TextArea.Value(:)', {message}];
%                   message_disp=sprintf(app.TextArea.Value+message+newline);
%             end
%               %app.TextArea.Value = compose(message_disp);
%               app.TextArea.Value = "";
%               app.TextArea.Value = message_disp;

            %app.TextArea.Value=mat2str(app.pnts1_matrix);
        end

        % Button pushed function: PointTransformButton
        function PointTransformButtonPushed(app, event)
            [rot, trans, err]=absoluteOrientationQuaternion(app.pnts1_matrix, app.pnts2_matrix);
            tform = rigidtform3d(rot,trans);
            pc1 = pcread("RealsenseB2-cleaned.ply");
            pc2 = pcread("RealsenseA2.ply");
            pcNew=pctransform(pc1,tform);

            showPair(pc1,pc2,"Before");
            showPair(pcNew,pc2,"After");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'GetData';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.PointTransformButton = uibutton(app.UIFigure, 'push');
            app.PointTransformButton.ButtonPushedFcn = createCallbackFcn(app, @PointTransformButtonPushed, true);
            app.PointTransformButton.Text = 'PointTransform';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showFig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end